classdef bixo_control_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ShoulderPositionLabel         matlab.ui.control.Label
        ControlInterfaceLabel         matlab.ui.control.Label
        A5Label                       matlab.ui.control.Label
        A4Label                       matlab.ui.control.Label
        A2Label                       matlab.ui.control.Label
        A1Label                       matlab.ui.control.Label
        A3Label                       matlab.ui.control.Label
        DevelopmentversionUseforresearchpurposesonlyLabel  matlab.ui.control.Label
        DownLamp_A3                   matlab.ui.control.Lamp
        DownLampLabel                 matlab.ui.control.Label
        MovingLamp_A3                 matlab.ui.control.Lamp
        MidLabel                      matlab.ui.control.Label
        UpLamp_A3                     matlab.ui.control.Lamp
        UpLampLabel                   matlab.ui.control.Label
        Gauge_A4                      matlab.ui.control.Gauge
        Label_A4PWM                   matlab.ui.control.NumericEditField
        Label_A4Pressure              matlab.ui.control.NumericEditField
        PWMLabel_5                    matlab.ui.control.Label
        kPaLabel_5                    matlab.ui.control.Label
        Gauge_A5                      matlab.ui.control.Gauge
        Label_A5PWM                   matlab.ui.control.NumericEditField
        Label_A5Pressure              matlab.ui.control.NumericEditField
        PWMLabel_4                    matlab.ui.control.Label
        PWMLabel_3                    matlab.ui.control.Label
        kPaLabel_4                    matlab.ui.control.Label
        v20Label                      matlab.ui.control.Label
        PWMLabel_2                    matlab.ui.control.Label
        kPaLabel_2                    matlab.ui.control.Label
        PWMLabel                      matlab.ui.control.Label
        Label_A3PWM                   matlab.ui.control.NumericEditField
        kPaLabel                      matlab.ui.control.Label
        Label_A2PWM                   matlab.ui.control.NumericEditField
        Label_A2Pressure              matlab.ui.control.NumericEditField
        Label_A1PWM                   matlab.ui.control.NumericEditField
        Label_A1Pressure              matlab.ui.control.NumericEditField
        Gauge_A2                      matlab.ui.control.Gauge
        Gauge_A1                      matlab.ui.control.Gauge
        ConnectionandPreheatingPanel  matlab.ui.container.Panel
        kPaLabel_9                    matlab.ui.control.Label
        kPaLabel_8                    matlab.ui.control.Label
        toLabel_8                     matlab.ui.control.Label
        toLabel_7                     matlab.ui.control.Label
        kPaLabel_7                    matlab.ui.control.Label
        kPaLabel_6                    matlab.ui.control.Label
        toLabel_6                     matlab.ui.control.Label
        toLabel_5                     matlab.ui.control.Label
        Button_SaveLog                matlab.ui.control.StateButton
        Button_A2PH                   matlab.ui.control.Button
        Button_A5PH                   matlab.ui.control.Button
        Button_A4PH                   matlab.ui.control.Button
        EditField_A2PHPressure        matlab.ui.control.NumericEditField
        EditField_A4PHPressure        matlab.ui.control.NumericEditField
        EditField_A5PHPressure        matlab.ui.control.NumericEditField
        EditField_A1PHPressure        matlab.ui.control.NumericEditField
        Button_A1PH                   matlab.ui.control.Button
        Button_stop                   matlab.ui.control.StateButton
        SerialPortDropDown            matlab.ui.control.DropDown
        SerialPortDropDownLabel       matlab.ui.control.Label
        Button_coms                   matlab.ui.control.Button
        MotionControlPanel            matlab.ui.container.Panel
        ShoulderSliderLabel           matlab.ui.control.Label
        toLabel_4                     matlab.ui.control.Label
        fromLabel_4                   matlab.ui.control.Label
        fromLabel_3                   matlab.ui.control.Label
        toLabel_3                     matlab.ui.control.Label
        toLabel_2                     matlab.ui.control.Label
        fromLabel_2                   matlab.ui.control.Label
        toLabel                       matlab.ui.control.Label
        fromLabel                     matlab.ui.control.Label
        GoDownButton_A3               matlab.ui.control.StateButton
        DuoGoButton_A4A5              matlab.ui.control.StateButton
        DuoGoButton_A1A2              matlab.ui.control.StateButton
        Lamp_A5                       matlab.ui.control.Lamp
        LowerLabel_3                  matlab.ui.control.Label
        Lamp_A4                       matlab.ui.control.Lamp
        LowerLabel_2                  matlab.ui.control.Label
        GoButton_A5                   matlab.ui.control.StateButton
        EditField_A5Max               matlab.ui.control.NumericEditField
        EditField_A5Min               matlab.ui.control.NumericEditField
        GoUpButton_A3                 matlab.ui.control.StateButton
        GoButton_A4                   matlab.ui.control.StateButton
        GoButton_A2                   matlab.ui.control.StateButton
        GoButton_A1                   matlab.ui.control.StateButton
        EditField_A4Max               matlab.ui.control.NumericEditField
        EditField_A2Max               matlab.ui.control.NumericEditField
        EditField_A1Max               matlab.ui.control.NumericEditField
        EditField_A4Min               matlab.ui.control.NumericEditField
        EditField_A2Min               matlab.ui.control.NumericEditField
        EditField_A1Min               matlab.ui.control.NumericEditField
        Lamp_A3                       matlab.ui.control.Lamp
        LowerLabel                    matlab.ui.control.Label
        Lamp_A2                       matlab.ui.control.Lamp
        CenterLabel                   matlab.ui.control.Label
        Lamp_A1                       matlab.ui.control.Lamp
        TopLabel                      matlab.ui.control.Label
        BIXOLabel                     matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        minCoordinate = [20 20 0 20 20]; %min pressure of 20 kPa
        maxCoordinate = [130 130 1 130 130]; %max pressure of 130 kPa
        setCoordinate = zeros(1 , 5); %(1) A1, (2) A2, etc...
        currState = zeros(1 , 10); %(1) press A1, (2) press A2, (...), (6) pwm A6, (7) pwm A7, (...)
        controller; % serial object
        connected = false; % connection flag
        preheated = zeros(1 , 5); % 0 = off, 1 = preheating, 2 = ready
        go = [false, false, false, false, false];
        commandLog= [];  % timetable to store data.
        stop = false;
    end
    
    methods (Access = private)


        function current_state = sendController(app, set_pressure)
            comsFault=1;
            current_state = zeros(1,10);   %preallocate for speed [press A1, press A2, ..., pwm A1, pwm A2, ...]
            % Send set_pressure, confirm sent data, receieve current_pressure
            while (comsFault == 1)
                flush(app.controller); % flush buffer
                write(app.controller, set_pressure,"uint8");
                % Receieve set pressure confirmation and current pressure
                msg = read(app.controller, 15, 'uint8');
                flush(app.controller);
                if (length(msg) == 15 && msg(1) == set_pressure(1) && msg(2) == set_pressure(2) && msg(3) == set_pressure(3) && msg(4) == set_pressure(4)) && msg(5) == set_pressure(5)
                    for i=1:10
                         current_state(i) = msg(i+5);
                    end
                    comsFault = 0;
                end
            end 
        end

        
        function setMotorStateLights(app, currentState)
            if (currentState == 10) % 10 means shoulder up
                app.UpLamp_A3.Color='g';
                app.MovingLamp_A3.Color='w';
                app.DownLamp_A3.Color='w';
            elseif (currentState == 01) % 01 means shoulder down
                app.UpLamp_A3.Color='w';
                app.MovingLamp_A3.Color='w';
                app.DownLamp_A3.Color='g';
            elseif (currentState == 11) % 11 means both switches are pressed (something is wrong then)
                app.UpLamp_A3.Color='r';
                app.MovingLamp_A3.Color='w';
                app.DownLamp_A3.Color='r';
            elseif (currentState == 00) % 00 means neither switch is pressed (shoulder moving)
                app.UpLamp_A3.Color='w';
                app.MovingLamp_A3.Color='y';
                app.DownLamp_A3.Color='w';
            end
        end
        
        function updateMotorCommandButtons(app)
            if (app.currState(3) == 01) % deactivate button if robot has reached the limit
                app.GoDownButton_A3.Value = false;
            elseif (app.currState(3) == 10)
                app.GoUpButton_A3.Value = false;
            end
        end
    end 


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Define initial values to all app components
                %Disable buttons
                app.GoButton_A1.Enable=false;
                app.GoButton_A2.Enable=false;
                app.GoUpButton_A3.Enable=false;
                app.GoDownButton_A3.Enable=false;
                app.GoButton_A4.Enable=false;
                app.GoButton_A5.Enable=false;
                app.DuoGoButton_A1A2.Enable=false;
                app.DuoGoButton_A4A5.Enable=false;
                app.Button_A1PH.Enable=false;
                app.Button_A2PH.Enable=false;
                app.Button_A4PH.Enable=false;
                app.Button_A5PH.Enable=false;
                app.Button_stop.Enable=false;
                
                %Set lamps to white (non-active state)
                app.Lamp_A1.Color='w';
                app.Lamp_A2.Color='w';
                app.Lamp_A3.Color='w';
                app.Lamp_A4.Color='w';
                app.Lamp_A5.Color='w';
                app.DownLamp_A3.Color='w';
                app.MovingLamp_A3.Color='w';
                app.UpLamp_A3.Color='w';

                %Set scales and default min/max pressure values
                app.EditField_A1Min.Value = app.minCoordinate(1);
                app.EditField_A2Min.Value = app.minCoordinate(2);
                app.EditField_A4Min.Value = app.minCoordinate(4);
                app.EditField_A5Min.Value = app.minCoordinate(5);

                app.EditField_A1Max.Value = app.maxCoordinate(1);
                app.EditField_A2Max.Value = app.maxCoordinate(2);
                app.EditField_A4Max.Value = app.maxCoordinate(4);
                app.EditField_A5Max.Value = app.maxCoordinate(5);

                app.Gauge_A1.Limits = [0 app.maxCoordinate(1)+10];
                app.Gauge_A2.Limits = [0 app.maxCoordinate(2)+10];
                app.Gauge_A4.Limits = [0 app.maxCoordinate(4)+10];
                app.Gauge_A5.Limits = [0 app.maxCoordinate(5)+10];
                
                %Search for available COM ports
                ports = serialportlist("available");
                app.SerialPortDropDown.Items=ports;

                 %% Continuous update loop   % SUBSTITUIR POR DISPLAY DE VALORES DE PRESSÃO
                 while (true)
                    %Preheat cycle 
                     if app.preheated(1) == 1 %command to preheat A1
                        %Set preheat pressure
                        app.setCoordinate(1) = app.EditField_A1PHPressure.Value;
                        %Check if pressure has reached preheat target
                        if (app.currState(1) >= app.EditField_A1PHPressure.Value - 3) %3 kPa error margin
                            app.preheated(1) = 2; % 2 corresponds to preheated state
                            %Standby setPressure value
                            app.setCoordinate(1) = app.minCoordinate(1);
                            %GUI
                            app.Lamp_A1.Color = 'g';
                            app.GoButton_A1.Enable = true;
                        end
                    end
                    if app.preheated(2) == 1 %command to preheat A2
                        %Set preheat pressure
                        app.setCoordinate(2) = app.EditField_A5PHPressure.Value;
                        %Check if pressure has reached preheat target
                        if (app.currState(2) >= app.EditField_A2PHPressure.Value - 3) %3 kPa error margin
                            app.preheated(2) = 2; % 2 corresponds to preheated state
                            %Standby setPressure value
                            app.setCoordinate(2) = app.minCoordinate(2);
                            %GUI
                            app.Lamp_A2.Color = 'g';
                            app.GoButton_A2.Enable = true;
                        end
                    end
                    if app.preheated(4) == 1 %command to preheat A4
                        %Set preheat pressure
                        app.setCoordinate(4) = app.EditField_A4PHPressure.Value;
                        %Check if pressure has reached preheat target
                        if (app.currState(4) >= app.EditField_A4PHPressure.Value - 3) %3 kPa error margin
                            app.preheated(4) = 2; % 2 corresponds to preheated state
                            %Standby setPressure value
                            app.setCoordinate(4) = app.minCoordinate(4);
                            %GUI
                            app.Lamp_A4.Color = 'g';
                            app.GoButton_A4.Enable = true;
                        end
                    end
                    if app.preheated(5) == 1 %command to preheat A5
                        %Set preheat pressure
                        app.setCoordinate(5) = app.EditField_A5PHPressure.Value;
                        %Check if pressure has reached preheat target
                        if (app.currState(5) >= app.EditField_A5PHPressure.Value - 3) %3 kPa error margin
                            app.preheated(5) = 2; % 2 corresponds to preheated state
                            %Standby setPressure value
                            app.setCoordinate(5) = app.minCoordinate(5);
                            %GUI
                            app.Lamp_A5.Color = 'g';
                            app.GoButton_A5.Enable = true;
                        end
                    end
                    
                    % workarounds to recycle code
                    app.preheated(3) = 2; % pretend A3 (the motor) is also preheated. This is just an adaptation to run the code from App v1.2
                    if (app.connected == true)
                        app.GoUpButton_A3.Enable=true;
                        app.GoDownButton_A3.Enable=true;
                    end

                    % Enable "double go" buttons
                    if app.GoButton_A1.Enable == true && app.GoButton_A2.Enable == true
                        app.DuoGoButton_A1A2.Enable = true;
                    end
                    if app.GoButton_A4.Enable == true && app.GoButton_A5.Enable == true
                        app.DuoGoButton_A4A5.Enable = true;
                    end
                    
                    %Running state
                    for i=1:5
                        if app.preheated(i) == 2
                            % Define setCoordinate to min or max value according to GO button
                            app.setCoordinate(i) = ~app.go(i) * app.minCoordinate(i) + app.go(i) * app.maxCoordinate(i); 
                        end
                    end
           
                    % If connected, send set pressure, confirm sent data, receieve current pressures
                    if app.connected == true
                        app.setCoordinate = app.setCoordinate * ~app.stop; % set to zero if STOP button was pressed
                        app.currState=app.sendController(app.setCoordinate);
                    end

                    % Update display of current pressures on dials
                    app.Gauge_A1.Value = app.currState(1);
                    app.Label_A1Pressure.Value = app.currState(1);
                    app.Gauge_A2.Value = app.currState(2);
                    app.Label_A2Pressure.Value = app.currState(2);
                    app.Gauge_A4.Value = app.currState(4);
                    app.Label_A4Pressure.Value = app.currState(4);
                    app.Gauge_A5.Value = app.currState(5);
                    app.Label_A5Pressure.Value = app.currState(5);

                    % Update shoulder state lights and command buttons
                    app.setMotorStateLights(app.currState(3));
                    app.updateMotorCommandButtons();
                    
                    % Update display of current pwm values
                    app.Label_A1PWM.Value = app.currState(6);
                    app.Label_A2PWM.Value = app.currState(7);
                    app.Label_A3PWM.Value = app.currState(8);
                    app.Label_A4PWM.Value = app.currState(9);
                    app.Label_A5PWM.Value = app.currState(10);

                    % Log current values in timetable
                    newLog = timetable(seconds(toc), app.setCoordinate, app.currState, 'VariableNames', ["setCoordinate", "currState"]);  % timetable to store data.
                    app.commandLog= vertcat(app.commandLog, newLog); % concatenate with previous logged data
                    
                    drawnow(); %To make this loop interruptible
                 end
        end

        % Button pushed function: Button_coms
        function Button_comsPushed(app, event)
            %% Hand Shake with Arduino
            app.controller=serialport(app.SerialPortDropDown.Value,57600);
            configureTerminator(app.controller, 'CR/LF');
            a='b';
            while(a~='a')
                a=readline(app.controller);
            end
            writeline(app.controller,"a");
            pause(0.001);
            flush(app.controller)

            %% Set connection flag 
            app.connected = true;

            %% GUI
            app.Button_A1PH.Enable=true;
            app.Button_A2PH.Enable=true;
            app.Button_A4PH.Enable=true;
            app.Button_A5PH.Enable=true;
            app.Button_stop.Enable=true;
            app.Button_coms.Text = 'Connected';
            app.Button_coms.Enable = false;
        end

        % Button pushed function: Button_A1PH
        function Button_A1PHPushed(app, event)
            %% Disable edit field and button
            app.EditField_A1PHPressure.Editable = false;
            app.Button_A1PH.Enable = false;
            app.Button_A1PH.Text = "preheating";

            %% Change color of lights to yellow (heating)
            app.Lamp_A1.Color = 'y';
            
            %% Set controller property (preheated) to preheat state (=1)  
            app.preheated(1) = 1;

        end

        % Button pushed function: Button_A2PH
        function Button_A2PHPushed(app, event)
            %% Disable edit field and button
            app.EditField_A2PHPressure.Editable = false;
            app.Button_A2PH.Enable = false;
            app.Button_A2PH.Text = "preheating";

            %% Change color of lights to yellow (heating)
            app.Lamp_A2.Color = 'y';
            
            %% Set controller property (preheated) to preheat state (=1)  
            app.preheated(2) = 1;
        end

        % Callback function
        function Button_A3PHPushed(app, event)

        end

        % Button pushed function: Button_A4PH
        function Button_A4PHPushed(app, event)
            %% Disable edit field and button
            app.EditField_A4PHPressure.Editable = false;
            app.Button_A4PH.Enable = false;
            app.Button_A4PH.Text = "preheating";

            %% Change color of lights to yellow (heating)
            app.Lamp_A4.Color = 'y';
            
            %% Set controller property (preheated) to preheat state (=1)  
            app.preheated(4) = 1;
        end

        % Button pushed function: Button_A5PH
        function Button_A5PHPushed(app, event)
            %% Disable edit field and button
            app.EditField_A5PHPressure.Editable = false;
            app.Button_A5PH.Enable = false;
            app.Button_A5PH.Text = "preheating";

            %% Change color of lights to yellow (heating)
            app.Lamp_A5.Color = 'y';
            
            %% Set controller property (preheated) to preheat state (=1)  
            app.preheated(5) = 1;    
        end

        % Value changed function: EditField_A1Max
        function EditField_A1MaxValueChanged(app, event)
            %Save new maximum pressure value
            app.maxCoordinate(1) = app.EditField_A1Max.Value;
        end

        % Value changed function: EditField_A2Max
        function EditField_A2MaxValueChanged(app, event)
            %Save new maximum pressure value
            app.maxCoordinate(2) = app.EditField_A2Max.Value;
        end

        % Callback function
        function EditField_A3MaxValueChanged(app, event)

        end

        % Value changed function: EditField_A4Max
        function EditField_A4MaxValueChanged(app, event)
            %Save new maximum pressure value
            app.maxCoordinate(4) = app.EditField_A4Max.Value;
        end

        % Value changed function: EditField_A5Max
        function EditField_A5MaxValueChanged(app, event)
            %Save new maximum pressure value
            app.maxCoordinate(5) = app.EditField_A5Max.Value;
        end

        % Value changed function: EditField_A1Min
        function EditField_A1MinValueChanged(app, event)
            %Save new minimum pressure value
            app.minCoordinate(1) = app.EditField_A1Min.Value;
        end

        % Value changed function: EditField_A2Min
        function EditField_A2MinValueChanged(app, event)
            %Save new minimum pressure value
            app.minCoordinate(2) = app.EditField_A2Min.Value;
        end

        % Callback function
        function EditField_A3MinValueChanged(app, event)

        end

        % Value changed function: EditField_A4Min
        function EditField_A4MinValueChanged(app, event)
            %Save new minimum pressure value
            app.minCoordinate(4) = app.EditField_A4Min.Value;
        end

        % Value changed function: EditField_A5Min
        function EditField_A5MinValueChanged(app, event)
            %Save new minimum pressure value
            app.minCoordinate(5) = app.EditField_A5Min.Value;     
        end

        % Value changed function: GoButton_A1
        function GoButton_A1ValueChanged(app, event)
            app.go(1) = app.GoButton_A1.Value;
        end

        % Value changed function: GoButton_A2
        function GoButton_A2ValueChanged(app, event)
            app.go(2) = app.GoButton_A2.Value;
        end

        % Value changed function: GoUpButton_A3
        function GoUpButton_A3ValueChanged(app, event)
            app.GoDownButton_A3.Value = false;
            app.go(3) = app.GoUpButton_A3.Value;
        end

        % Value changed function: GoButton_A4
        function GoButton_A4ValueChanged(app, event)
            app.go(4) = app.GoButton_A4.Value;
        end

        % Value changed function: GoButton_A5
        function GoButton_A5ValueChanged(app, event)
            app.go(5) = app.GoButton_A5.Value;
        end

        % Value changed function: DuoGoButton_A1A2
        function DuoGoButton_A1A2ValueChanged(app, event)
            app.GoButton_A1.Value = app.DuoGoButton_A1A2.Value;
            app.GoButton_A2.Value = app.DuoGoButton_A1A2.Value;
            app.go(1) = app.GoButton_A1.Value;   % not sure why don't the previous two lines activate the GoButton_AxValueChanged functions
            app.go(2) = app.GoButton_A2.Value;
        end

        % Value changed function: DuoGoButton_A4A5
        function DuoGoButton_A4A5ValueChanged(app, event)
            app.GoButton_A4.Value = app.DuoGoButton_A4A5.Value;
            app.GoButton_A5.Value = app.DuoGoButton_A4A5.Value;
            app.go(4) = app.GoButton_A4.Value;   % not sure why don't the previous two lines activate the GoButton_AxValueChanged functions
            app.go(5) = app.GoButton_A5.Value;
        end

        % Value changed function: Button_stop
        function Button_stopValueChanged(app, event)
            app.Button_stop.Enable = false;
            app.stop = true;
        end

        % Value changed function: GoDownButton_A3
        function GoDownButton_A3ValueChanged(app, event)
            app.GoUpButton_A3.Value = false;   % deactivate oposite button
            app.go(3) = ~app.GoDownButton_A3.Value;   % send 0 (move down) to robot if button is pressed
        end

        % Value changed function: Button_SaveLog
        function Button_SaveLogValueChanged(app, event)
            data = app.commandLog;
            save('commandLog.mat', 'data');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'bkg_diagram.png');
            app.BIXOLabel = uilabel(app.UIFigure);
            app.BIXOLabel.Text = 'BIXO';
            app.MotionControlPanel = uipanel(app.UIFigure);
            app.MotionControlPanel.Title = 'Motion Control';
            app.TopLabel = uilabel(app.MotionControlPanel);
            app.TopLabel.Text = 'A1';
            app.Lamp_A1 = uilamp(app.MotionControlPanel);
            app.CenterLabel = uilabel(app.MotionControlPanel);
            app.CenterLabel.Text = 'A2';
            app.Lamp_A2 = uilamp(app.MotionControlPanel);
            app.LowerLabel = uilabel(app.MotionControlPanel);
            app.LowerLabel.Text = 'A3';
            app.Lamp_A3 = uilamp(app.MotionControlPanel);
            app.EditField_A1Min = uieditfield(app.MotionControlPanel, 'numeric');
            app.EditField_A1Min.ValueChangedFcn = createCallbackFcn(app, @EditField_A1MinValueChanged, true);
            app.EditField_A2Min = uieditfield(app.MotionControlPanel, 'numeric');
            app.EditField_A2Min.ValueChangedFcn = createCallbackFcn(app, @EditField_A2MinValueChanged, true);
            app.EditField_A4Min = uieditfield(app.MotionControlPanel, 'numeric');
            app.EditField_A4Min.ValueChangedFcn = createCallbackFcn(app, @EditField_A4MinValueChanged, true);
            app.EditField_A1Max = uieditfield(app.MotionControlPanel, 'numeric');
            app.EditField_A1Max.ValueChangedFcn = createCallbackFcn(app, @EditField_A1MaxValueChanged, true);
            app.EditField_A2Max = uieditfield(app.MotionControlPanel, 'numeric');
            app.EditField_A2Max.ValueChangedFcn = createCallbackFcn(app, @EditField_A2MaxValueChanged, true);
            app.EditField_A4Max = uieditfield(app.MotionControlPanel, 'numeric');
            app.EditField_A4Max.ValueChangedFcn = createCallbackFcn(app, @EditField_A4MaxValueChanged, true);
            app.GoButton_A1 = uibutton(app.MotionControlPanel, 'state');
            app.GoButton_A1.ValueChangedFcn = createCallbackFcn(app, @GoButton_A1ValueChanged, true);
            app.GoButton_A1.Text = 'Pressurize';
            app.GoButton_A2 = uibutton(app.MotionControlPanel, 'state');
            app.GoButton_A2.ValueChangedFcn = createCallbackFcn(app, @GoButton_A2ValueChanged, true);
            app.GoButton_A2.Text = 'Pressurize';
            app.GoButton_A4 = uibutton(app.MotionControlPanel, 'state');
            app.GoButton_A4.ValueChangedFcn = createCallbackFcn(app, @GoButton_A4ValueChanged, true);
            app.GoButton_A4.Text = 'Pressurize';
            app.GoUpButton_A3 = uibutton(app.MotionControlPanel, 'state');
            app.GoUpButton_A3.ValueChangedFcn = createCallbackFcn(app, @GoUpButton_A3ValueChanged, true);
            app.GoUpButton_A3.Text = 'Up';
            app.EditField_A5Min = uieditfield(app.MotionControlPanel, 'numeric');
            app.EditField_A5Min.ValueChangedFcn = createCallbackFcn(app, @EditField_A5MinValueChanged, true);
            app.EditField_A5Max = uieditfield(app.MotionControlPanel, 'numeric');
            app.EditField_A5Max.ValueChangedFcn = createCallbackFcn(app, @EditField_A5MaxValueChanged, true);
            app.GoButton_A5 = uibutton(app.MotionControlPanel, 'state');
            app.GoButton_A5.ValueChangedFcn = createCallbackFcn(app, @GoButton_A5ValueChanged, true);
            app.GoButton_A5.Text = 'Pressurize';
            app.LowerLabel_2 = uilabel(app.MotionControlPanel);
            app.LowerLabel_2.Text = 'A4';
            app.Lamp_A4 = uilamp(app.MotionControlPanel);
            app.LowerLabel_3 = uilabel(app.MotionControlPanel);
            app.LowerLabel_3.Text = 'A5';
            app.Lamp_A5 = uilamp(app.MotionControlPanel);
            app.DuoGoButton_A1A2 = uibutton(app.MotionControlPanel, 'state');
            app.DuoGoButton_A1A2.ValueChangedFcn = createCallbackFcn(app, @DuoGoButton_A1A2ValueChanged, true);
            app.DuoGoButton_A1A2.Text = {'Pressurize'; 'A1 and A2'};
            app.DuoGoButton_A4A5 = uibutton(app.MotionControlPanel, 'state');
            app.DuoGoButton_A4A5.ValueChangedFcn = createCallbackFcn(app, @DuoGoButton_A4A5ValueChanged, true);
            app.DuoGoButton_A4A5.Text = {'Pressurize'; 'A4 and A5'};
            app.GoDownButton_A3 = uibutton(app.MotionControlPanel, 'state');
            app.GoDownButton_A3.ValueChangedFcn = createCallbackFcn(app, @GoDownButton_A3ValueChanged, true);
            app.GoDownButton_A3.Text = 'Down';
            app.fromLabel = uilabel(app.MotionControlPanel);
            app.fromLabel.Text = 'from';
            app.toLabel = uilabel(app.MotionControlPanel);
            app.toLabel.Text = 'to';
            app.fromLabel_2 = uilabel(app.MotionControlPanel);
            app.fromLabel_2.Text = 'from';
            app.toLabel_2 = uilabel(app.MotionControlPanel);
            app.toLabel_2.Text = 'to';
            app.toLabel_3 = uilabel(app.MotionControlPanel);
            app.toLabel_3.Text = 'to';
            app.fromLabel_3 = uilabel(app.MotionControlPanel);
            app.fromLabel_3.Text = 'from';
            app.fromLabel_4 = uilabel(app.MotionControlPanel);
            app.fromLabel_4.Text = 'from';
            app.toLabel_4 = uilabel(app.MotionControlPanel);
            app.toLabel_4.Text = 'to';
            app.ShoulderSliderLabel = uilabel(app.MotionControlPanel);
            app.ShoulderSliderLabel.Text = 'Shoulder Slider';
            app.ConnectionandPreheatingPanel = uipanel(app.UIFigure);
            app.ConnectionandPreheatingPanel.Title = 'Connection and Preheating';
            app.Button_coms = uibutton(app.ConnectionandPreheatingPanel, 'push');
            app.Button_coms.ButtonPushedFcn = createCallbackFcn(app, @Button_comsPushed, true);
            app.Button_coms.Text = 'Connect';
            app.SerialPortDropDownLabel = uilabel(app.ConnectionandPreheatingPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.ConnectionandPreheatingPanel);
            app.Button_stop = uibutton(app.ConnectionandPreheatingPanel, 'state');
            app.Button_stop.ValueChangedFcn = createCallbackFcn(app, @Button_stopValueChanged, true);
            app.Button_stop.Text = 'STOP';
            app.Button_A1PH = uibutton(app.ConnectionandPreheatingPanel, 'push');
            app.Button_A1PH.ButtonPushedFcn = createCallbackFcn(app, @Button_A1PHPushed, true);
            app.Button_A1PH.Text = 'Preheat A1';
            app.EditField_A1PHPressure = uieditfield(app.ConnectionandPreheatingPanel, 'numeric');
            app.EditField_A1PHPressure.Value = 40;
            app.EditField_A5PHPressure = uieditfield(app.ConnectionandPreheatingPanel, 'numeric');
            app.EditField_A5PHPressure.Value = 40;
            app.EditField_A4PHPressure = uieditfield(app.ConnectionandPreheatingPanel, 'numeric');
            app.EditField_A4PHPressure.Value = 40;
            app.EditField_A2PHPressure = uieditfield(app.ConnectionandPreheatingPanel, 'numeric');
            app.EditField_A2PHPressure.Value = 40;
            app.Button_A4PH = uibutton(app.ConnectionandPreheatingPanel, 'push');
            app.Button_A4PH.ButtonPushedFcn = createCallbackFcn(app, @Button_A4PHPushed, true);
            app.Button_A4PH.Text = 'Preheat A4';
            app.Button_A5PH = uibutton(app.ConnectionandPreheatingPanel, 'push');
            app.Button_A5PH.ButtonPushedFcn = createCallbackFcn(app, @Button_A5PHPushed, true);
            app.Button_A5PH.Text = 'Preheat A5';
            app.Button_A2PH = uibutton(app.ConnectionandPreheatingPanel, 'push');
            app.Button_A2PH.ButtonPushedFcn = createCallbackFcn(app, @Button_A2PHPushed, true);
            app.Button_A2PH.Text = 'Preheat A2';
            app.Button_SaveLog = uibutton(app.ConnectionandPreheatingPanel, 'state');
            app.Button_SaveLog.ValueChangedFcn = createCallbackFcn(app, @Button_SaveLogValueChanged, true);
            app.Button_SaveLog.Text = 'Save';
            app.toLabel_5 = uilabel(app.ConnectionandPreheatingPanel);
            app.toLabel_5.Text = 'to';
            app.toLabel_6 = uilabel(app.ConnectionandPreheatingPanel);
            app.toLabel_6.Text = 'to';
            app.kPaLabel_6 = uilabel(app.ConnectionandPreheatingPanel);
            app.kPaLabel_6.Text = 'kPa';
            app.kPaLabel_7 = uilabel(app.ConnectionandPreheatingPanel);
            app.kPaLabel_7.Text = 'kPa';
            app.toLabel_7 = uilabel(app.ConnectionandPreheatingPanel);
            app.toLabel_7.Text = 'to';
            app.toLabel_8 = uilabel(app.ConnectionandPreheatingPanel);
            app.toLabel_8.Text = 'to';
            app.kPaLabel_8 = uilabel(app.ConnectionandPreheatingPanel);
            app.kPaLabel_8.Text = 'kPa';
            app.kPaLabel_9 = uilabel(app.ConnectionandPreheatingPanel);
            app.kPaLabel_9.Text = 'kPa';
            app.Gauge_A1 = uigauge(app.UIFigure, 'circular');
            app.Gauge_A2 = uigauge(app.UIFigure, 'circular');
            app.Label_A1Pressure = uieditfield(app.UIFigure, 'numeric');
            app.Label_A1PWM = uieditfield(app.UIFigure, 'numeric');
            app.Label_A2Pressure = uieditfield(app.UIFigure, 'numeric');
            app.Label_A2PWM = uieditfield(app.UIFigure, 'numeric');
            app.kPaLabel = uilabel(app.UIFigure);
            app.kPaLabel.Text = 'kPa';
            app.Label_A3PWM = uieditfield(app.UIFigure, 'numeric');
            app.PWMLabel = uilabel(app.UIFigure);
            app.PWMLabel.Text = 'PWM';
            app.kPaLabel_2 = uilabel(app.UIFigure);
            app.kPaLabel_2.Text = 'kPa';
            app.PWMLabel_2 = uilabel(app.UIFigure);
            app.PWMLabel_2.Text = 'PWM';
            app.v20Label = uilabel(app.UIFigure);
            app.v20Label.Text = 'v2.0';
            app.kPaLabel_4 = uilabel(app.UIFigure);
            app.kPaLabel_4.Text = 'kPa';
            app.PWMLabel_3 = uilabel(app.UIFigure);
            app.PWMLabel_3.Text = 'PWM';
            app.PWMLabel_4 = uilabel(app.UIFigure);
            app.PWMLabel_4.Text = 'PWM';
            app.Label_A5Pressure = uieditfield(app.UIFigure, 'numeric');
            app.Label_A5PWM = uieditfield(app.UIFigure, 'numeric');
            app.Gauge_A5 = uigauge(app.UIFigure, 'circular');
            app.kPaLabel_5 = uilabel(app.UIFigure);
            app.kPaLabel_5.Text = 'kPa';
            app.PWMLabel_5 = uilabel(app.UIFigure);
            app.PWMLabel_5.Text = 'PWM';
            app.Label_A4Pressure = uieditfield(app.UIFigure, 'numeric');
            app.Label_A4PWM = uieditfield(app.UIFigure, 'numeric');
            app.Gauge_A4 = uigauge(app.UIFigure, 'circular');
            app.UpLampLabel = uilabel(app.UIFigure);
            app.UpLampLabel.Text = 'Up';
            app.UpLamp_A3 = uilamp(app.UIFigure);
            app.MidLabel = uilabel(app.UIFigure);
            app.MidLabel.Text = 'Mid';
            app.MovingLamp_A3 = uilamp(app.UIFigure);
            app.DownLampLabel = uilabel(app.UIFigure);
            app.DownLampLabel.Text = 'Down';
            app.DownLamp_A3 = uilamp(app.UIFigure);
            app.DevelopmentversionUseforresearchpurposesonlyLabel = uilabel(app.UIFigure);
            app.DevelopmentversionUseforresearchpurposesonlyLabel.Text = 'Development version. Use for research purposes only.';
            app.A3Label = uilabel(app.UIFigure);
            app.A3Label.Text = 'A3';
            app.A1Label = uilabel(app.UIFigure);
            app.A1Label.Text = 'A1';
            app.A2Label = uilabel(app.UIFigure);
            app.A2Label.Text = 'A2';
            app.A4Label = uilabel(app.UIFigure);
            app.A4Label.Text = 'A4';
            app.A5Label = uilabel(app.UIFigure);
            app.A5Label.Text = 'A5';
            app.ControlInterfaceLabel = uilabel(app.UIFigure);
            app.ControlInterfaceLabel.Text = 'Control Interface';
            app.ShoulderPositionLabel = uilabel(app.UIFigure);
            app.ShoulderPositionLabel.Text = 'Shoulder Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bixo_control_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end